classdef app2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure              matlab.ui.Figure
        trainButton           matlab.ui.control.Button
        validateButton        matlab.ui.control.Button
        Lamp                  matlab.ui.control.Lamp
        EditField             matlab.ui.control.EditField
        recordandtestButton   matlab.ui.control.Button
        outputEditField       matlab.ui.control.EditField
        outputEditFieldLabel  matlab.ui.control.Label
        test_maleButton       matlab.ui.control.Button
        test_femaleButton     matlab.ui.control.Button
    end

    
    properties (Access = public)
        Property % Description
         i=0;
         net;
         test_output;
         X;
         T;
         features_vector;
    end
  

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: test_maleButton
        function test_maleButtonPushed(app, event)
        
cd 'C:\Users\walaa\Documents\MATLAB\neural_project'


 cd 'C:\Users\walaa\Documents\MATLAB\neural_project\men'
 [audioIn, fs] = audioread('m47uh.wav');
 sound(audioIn,fs)
 a=9.5;
windowLength = round((numel(audioIn)/(27660*a))*fs);
overlapLength = round((numel(audioIn)/(33192*a))*fs);

features = [];
melC = mfcc(audioIn,fs,'Window',hamming(windowLength,'periodic'),'OverlapLength',overlapLength);
f0 = pitch(audioIn,fs,'WindowLength',windowLength,'OverlapLength',overlapLength);
feat = [melC,f0];

features = [features;feat];
M = mean(features,1);
S = std(features,[],1);
features = (features-M)./S;
features_vector=reshape(features',[],1);
features_vector=features_vector(1:1155,:);
a= app.net(features_vector);
y=round(a)

if(y==1)
app.outputEditField.Value='female';
else
app.outputEditField.Value='male';    
end


        end

        % Button pushed function: test_femaleButton
        function test_femaleButtonPushed(app, event)
               cd 'C:\Users\walaa\Documents\MATLAB\neural_project'
                             
X=[];
T=[];

 cd 'C:\Users\walaa\Documents\MATLAB\neural_project\women'
 [audioIn, fs] = audioread('w46uh.wav');
 sound(audioIn,fs)
 a=9.5;
windowLength = round((numel(audioIn)/(27660*a))*fs);
overlapLength = round((numel(audioIn)/(33192*a))*fs);

features = [];
melC = mfcc(audioIn,fs,'Window',hamming(windowLength,'periodic'),'OverlapLength',overlapLength);
f0 = pitch(audioIn,fs,'WindowLength',windowLength,'OverlapLength',overlapLength);
feat = [melC,f0];

features = [features;feat];
M = mean(features,1);
S = std(features,[],1);
features = (features-M)./S;
features_vector=reshape(features',[],1);
features_vector=features_vector(1:1155,:);
a= app.net(features_vector);
y=round(a)
if(y==1)
app.outputEditField.Value='female';
else
app.outputEditField.Value='male';    
end


        end

        % Button pushed function: recordandtestButton
        function recordandtestButtonPushed(app, event)
          app.i=app.i+1;
            cd 'C:\Users\walaa\Documents\MATLAB\neural_project'
                          audio=audiorecorder(16000,8,1)
            app.EditField.Value="say Hello clearly"; 
            app.Lamp.Enable = 'on';
recordblocking(audio,3);
app.EditField.Value=""; 
            app.Lamp.Enable = 'off';
sound=getaudiodata(audio);
play(audio)
pause('on')
pause(2);
filename ="online_testing\audioIn"+num2str(app.i)+".wav";
audiowrite(filename, sound, 16000);
new=[];
k=numel(sound);
j=0;
for i=1 : k
    if(sound(i)~=0)
        j=j+1;
        new(j)=sound(i);
    end
end

new=new';
fs=16000;
a=9.5;
windowLength = round((numel(new)/(27660*a))*fs);
overlapLength = round((numel(new)/(33192*a))*fs);

features = [];
melC = mfcc(new,fs,'Window',hamming(windowLength,'periodic'),'OverlapLength',overlapLength);
f0 = pitch(new,fs,'WindowLength',windowLength,'OverlapLength',overlapLength);
feat = [melC,f0];

features = [features;feat];
M = mean(features,1);
S = std(features,[],1);
features = (features-M)./S;
features_vector=reshape(features',[],1);
features_vector=features_vector(1:1155,:);
app.features_vector=features_vector;

a= app.net(features_vector);
y=round(a)
app.test_output=y;
if(y==1)
app.outputEditField.Value='female';
else
app.outputEditField.Value='male';    
end


        end

        % Button pushed function: validateButton
        function validateButtonPushed(app, event)
            cd 'C:\Users\walaa\Documents\MATLAB\neural_project'
if(app.test_output==1)
    t=0;
else(app.test_output==0)
    t=1;
end  

app.X= [app.X app.features_vector];
app.T=[app.T t];

trainFcn = 'trainscg';  % Scaled conjugate gradient backpropagation.

% Create a Pattern Recognition Network
hiddenLayerSize = 10;
net = patternnet(hiddenLayerSize, trainFcn);

% Setup Division of Data for Training, Validation, Testing
net.divideParam.trainRatio = 100/100;
net.divideParam.valRatio = 0/100;
net.divideParam.testRatio = 0/100;

% Train the Network
[net,tr] = train(net,app.X,app.T);
 
app.net=net;
a=round(app.net(app.features_vector))
if(a==1)
app.outputEditField.Value='female';
else
app.outputEditField.Value='male';    
end


        end

        % Button pushed function: trainButton
        function trainButtonPushed(app, event)
            cd 'C:\Users\walaa\Documents\MATLAB\neural_project'
X=[];
T=[];
Files=dir(['women' '/*.wav']);
for k=1:length(Files)
 name=Files(k).name;
 cd 'C:\Users\walaa\Documents\MATLAB\neural_project\women'
 [audioIn, fs] = audioread(name);
 a=9.5;
windowLength = round((numel(audioIn)/(27660*a))*fs);
overlapLength = round((numel(audioIn)/(33192*a))*fs);

features = [];
melC = mfcc(audioIn,fs,'Window',hamming(windowLength,'periodic'),'OverlapLength',overlapLength);
f0 = pitch(audioIn,fs,'WindowLength',windowLength,'OverlapLength',overlapLength);
feat = [melC,f0];

features = [features;feat];
M = mean(features,1);
S = std(features,[],1);
features = (features-M)./S;
features_vector=reshape(features',[],1);
features_vector=features_vector(1:1155,:);
X(:,k) = features_vector;
T(k)=1;
end


cd 'C:\Users\walaa\Documents\MATLAB\neural_project'
Files=dir(['women_real' '/*.wav']);
for l=1:numel(Files)
 name=Files(l).name;
 cd 'C:\Users\walaa\Documents\MATLAB\neural_project\women_real'
 [audioIn, fs] = audioread(name);
 audioIn=reshape(audioIn,[],1);
 new=[];
n=numel(audioIn);
j=0;
for i=1 : n

    if(audioIn(i)~=0)
        j=j+1;
        new(j)=audioIn(i);
    end
end
audioIn=new';
fs=16000;
 a=9.5;
windowLength = round((numel(audioIn)/(27660*a))*fs);
overlapLength = round((numel(audioIn)/(33192*a))*fs);

features = [];
melC = mfcc(audioIn,fs,'Window',hamming(windowLength,'periodic'),'OverlapLength',overlapLength);
f0 = pitch(audioIn,fs,'WindowLength',windowLength,'OverlapLength',overlapLength);
feat = [melC,f0];

features = [features;feat];
M = mean(features,1);
S = std(features,[],1);
features = (features-M)./S;
features_vector=reshape(features',[],1);
features_vector=features_vector(1:1155,:);
X(:,k+l) = features_vector;
T(k+l)=1;
end


cd 'C:\Users\walaa\Documents\MATLAB\neural_project'

Files=dir(['men' '/*.wav']);
for g=1:length(Files)
 name=Files(g).name;
 cd 'C:\Users\walaa\Documents\MATLAB\neural_project\men'
 [audioIn, fs] = audioread(name);
 a=9.5;
windowLength = round((numel(audioIn)/(27660*a))*fs);
overlapLength = round((numel(audioIn)/(33192*a))*fs);
fs=16000;
features = [];
melC = mfcc(audioIn,fs,'Window',hamming(windowLength,'periodic'),'OverlapLength',overlapLength);
f0 = pitch(audioIn,fs,'WindowLength',windowLength,'OverlapLength',overlapLength);
feat = [melC,f0];

features = [features;feat];
M = mean(features,1);
S = std(features,[],1);
features = (features-M)./S;
features_vector=reshape(features',[],1);
features_vector=features_vector(1:1155,:);
X(:,k+l+g) = features_vector;
T(k+l+g)=0;
end

cd 'C:\Users\walaa\Documents\MATLAB\neural_project'

Files=dir(['men_real' '/*.wav']);
for m=1:length(Files)
 name=Files(m).name;
 cd 'C:\Users\walaa\Documents\MATLAB\neural_project\men_real'
 [audioIn, fs] = audioread(name);
 audioIn=reshape(audioIn,[],1);
 new=[];
n=numel(audioIn);
j=0;
for i=1 : n
    if(audioIn(i)~=0)
        j=j+1;
        new(j)=audioIn(i);
    end
end

audioIn=new';
 
fs=16000;
 a=9.5;
windowLength = round((numel(audioIn)/(27660*a))*fs);
overlapLength = round((numel(audioIn)/(33192*a))*fs);

features = [];
melC = mfcc(audioIn,fs,'Window',hamming(windowLength,'periodic'),'OverlapLength',overlapLength);
f0 = pitch(audioIn,fs,'WindowLength',windowLength,'OverlapLength',overlapLength);
feat = [melC,f0];

features = [features;feat];
M = mean(features,1);
S = std(features,[],1);
features = (features-M)./S;
features_vector=reshape(features',[],1);
features_vector=features_vector(1:1155,:);
X(:,k+l+g+m) = features_vector;
T(k+l+g+m)=0;
end


x = X;
t = T;


trainFcn = 'trainscg';  % Scaled conjugate gradient backpropagation.

% Create a Pattern Recognition Network
hiddenLayerSize = 10;
net = patternnet(hiddenLayerSize, trainFcn);

% Setup Division of Data for Training, Validation, Testing
net.divideParam.trainRatio = 100/100;
net.divideParam.valRatio = 0/100;
net.divideParam.testRatio = 0/100;

% Train the Network
[net,tr] = train(net,x,t);
app.net=net;
app.X=X;
app.T=T;

        end

        % Callback function
        function saveButtonPushed(app, event)
           

        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            cd 'C:\Users\walaa\Documents\MATLAB\neural_project'
            save("newnetwork.mat");
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.test_femaleButton = uibutton(app.UIFigure, 'push');
            app.test_femaleButton.ButtonPushedFcn = createCallbackFcn(app, @test_femaleButtonPushed, true);
            app.test_femaleButton.Text = 'test_female';
            app.test_maleButton = uibutton(app.UIFigure, 'push');
            app.test_maleButton.ButtonPushedFcn = createCallbackFcn(app, @test_maleButtonPushed, true);
            app.test_maleButton.Text = 'test_male';
            app.outputEditFieldLabel = uilabel(app.UIFigure);
            app.outputEditFieldLabel.Text = 'output';
            app.outputEditField = uieditfield(app.UIFigure, 'text');
            app.recordandtestButton = uibutton(app.UIFigure, 'push');
            app.recordandtestButton.ButtonPushedFcn = createCallbackFcn(app, @recordandtestButtonPushed, true);
            app.recordandtestButton.Text = 'record and test';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.Lamp = uilamp(app.UIFigure);
            app.Lamp.Enable = 'off';
            app.validateButton = uibutton(app.UIFigure, 'push');
            app.validateButton.ButtonPushedFcn = createCallbackFcn(app, @validateButtonPushed, true);
            app.validateButton.Text = 'validate';
            app.trainButton = uibutton(app.UIFigure, 'push');
            app.trainButton.ButtonPushedFcn = createCallbackFcn(app, @trainButtonPushed, true);
            app.trainButton.Text = 'train';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end